classdef Covid_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        StateorRegionListBox       matlab.ui.control.ListBox
        StateorRegionListBoxLabel  matlab.ui.control.Label
        CountryListBox             matlab.ui.control.ListBox
        CountryListBoxLabel        matlab.ui.control.Label
        OptionButtonGroup          matlab.ui.container.ButtonGroup
        DailyButton                matlab.ui.control.RadioButton
        CumulativeButton           matlab.ui.control.RadioButton
        DatatoPlotButtonGroup      matlab.ui.container.ButtonGroup
        BothButton                 matlab.ui.control.RadioButton
        DeathsButton               matlab.ui.control.RadioButton
        CasesButton                matlab.ui.control.RadioButton
        AveragedofdaysSlider       matlab.ui.control.Slider
        AveragedofdaysSliderLabel  matlab.ui.control.Label
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Dates
        GeneralData
        RegionValue
        DatatoPlotButton
        OptionButton
        AveragingWindow
        Data1XAxis
        Data2XAxis
        Data1Averaged
        Data2Averaged
    end
    
    methods (Access = private)

        function CalculateData(app)
            if strcmp(app.OptionButton, 'Cumulative')
                if  strcmp(app.DatatoPlotButton, 'Both')
                    if app.GeneralData.Country_I==1
                        app.Data1XAxis=sum_global_cases(app.GeneralData);              
                        app.Data2XAxis=sum_global_deaths(app.GeneralData);
                    else
                        app.Data1XAxis=CumulativeCases(app.GeneralData, app.RegionValue);
                        app.Data2XAxis= DeathCases(app.GeneralData,app.RegionValue);
                    end
                elseif strcmp(app.DatatoPlotButton,'Cases')
                    if app.GeneralData.Country_I==1
                        app.Data1XAxis= sum_global_cases(app.GeneralData);
                    else
                        app.Data1XAxis= CumulativeCases(app.GeneralData, app.RegionValue);
                    end
                    app.Data2XAxis=[];
                elseif strcmp(app.DatatoPlotButton, 'Deaths')
                    if app.GeneralData.Country_I==1
                        app.Data2XAxis= sum_global_deaths(app.GeneralData);
                    else
                        app.Data2XAxis= DeathCases(app.GeneralData, app.RegionValue);
                    end
                    app.Data1XAxis=[];
                end
            else
                daily_data=daily_cases_deaths(app.GeneralData, app.CountryListBox.Value,app.RegionValue );
                if  strcmp(app.DatatoPlotButton, 'Both')
                       app.Data1XAxis=daily_data(1,:);
                       app.Data2XAxis=daily_data(2,:);
                elseif strcmp(app.DatatoPlotButton,'Cases')
                       app.Data1XAxis=daily_data(1,:);
                       app.Data2XAxis=[];
                elseif strcmp(app.DatatoPlotButton, 'Deaths')
                        app.Data2XAxis=daily_data(2,:);
                        app.Data1XAxis=[];
                end
           end
        end %End function

        function ComputeAverages(app)
            if isempty(app.Data2XAxis)
                app.Data1Averaged=movmean(app.Data1XAxis, app.AveragingWindow);
            elseif isempty(app.Data1XAxis)
                app.Data2Averaged=movmean(app.Data2XAxis, app.AveragingWindow); 
            else 
                app.Data1Averaged=movmean(app.Data1XAxis, app.AveragingWindow);
                app.Data2Averaged=movmean(app.Data2XAxis, app.AveragingWindow); 
            end
        end %End function

        function PlotData(app)
            app.UIAxes.reset; 
            if isempty(app.Data2XAxis)
                bar(app.UIAxes, app.Dates, app.Data1Averaged,'b');
            elseif isempty(app.Data1XAxis)
                plot(app.UIAxes, app.Dates, app.Data2Averaged, 'r');
            else 
                yyaxis(app.UIAxes, 'left'); 
                bar(app.UIAxes, app.Dates, app.Data1Averaged,'b');
                yyaxis(app.UIAxes,'right'); 
                plot(app.UIAxes, app.Dates, app.Data2Averaged, 'r');
            end
            if strcmp(app.DatatoPlotButton, 'Both')
                if app.GeneralData.Country_I==1
                    title(app.UIAxes,{[app.OptionButton,' Number of Cases and Deaths ', 'Globally']});
                else 
                    if strcmp(app.RegionValue ,'All')
                       title(app.UIAxes,{[app.OptionButton,' Number of Cases and Deaths ', 'in ', app.CountryListBox.Value]});
                    else 
                       title(app.UIAxes,{[app.OptionButton,' Number of Cases and Deaths ', ' in ', app.RegionValue]});
                    end
                end
            else
                if app.GeneralData.Country_I==1
                    title(app.UIAxes,{[app.OptionButton,' Number of ', app.DatatoPlotButton, ' Globally']});
                else 
                    if strcmp(app.RegionValue ,'All')
                        title(app.UIAxes,{[app.OptionButton,' Number of ', app.DatatoPlotButton,' in ', app.CountryListBox.Value]});
                    else 
                        title(app.UIAxes,{[app.OptionButton,' Number of ', app.DatatoPlotButton,' in ', app.RegionValue]});
                    end
                end
            end
            datetick(app.UIAxes, 'x', 'mm/dd');
            hold(app.UIAxes,'on');
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           app.GeneralData=CovidClass('Global');
           app.CountryListBox.Items =unique(app.GeneralData.Country,'stable');
           app.StateorRegionListBox.Items = unique(app.GeneralData.States,'stable');
           app.Dates=app.GeneralData.Dates;
           app.BothButton.Value=true; 
           app.AveragingWindow=1;
           app.DatatoPlotButton='Both';
           app.OptionButton='Cumulative';
           app.RegionValue = 'All';
           CalculateData(app);
           ComputeAverages(app); 
           PlotData(app);
        end

        % Value changed function: CountryListBox
        function CountryListBoxValueChanged(app, event)
            app.GeneralData=CovidClass(app.CountryListBox.Value);
            app.StateorRegionListBox.Items = unique(app.GeneralData.States,'stable');
            CalculateData(app);
            ComputeAverages(app);
            PlotData(app);
        end

        % Value changed function: StateorRegionListBox
        function StateorRegionListBoxValueChanged(app, event)
            app.RegionValue = app.StateorRegionListBox.Value;
            CalculateData(app);
            ComputeAverages(app);
            PlotData(app);
        end

        % Selection changed function: DatatoPlotButtonGroup
        function DatatoPlotButtonGroupSelectionChanged(app, event)
            selectedButton = app.DatatoPlotButtonGroup.SelectedObject;
            app.DatatoPlotButton=selectedButton.Text;
            CalculateData(app);
            ComputeAverages(app);
            PlotData(app); 
        end

        % Selection changed function: OptionButtonGroup
        function OptionButtonGroupSelectionChanged(app, event)
            selectedButton = app.OptionButtonGroup.SelectedObject;
            app.OptionButton=selectedButton.Text;
            CalculateData(app); 
            ComputeAverages(app);
            PlotData(app);
        end

        % Value changing function: AveragedofdaysSlider
        function AveragedofdaysSliderValueChanging(app, event)
            changingValue = event.Value;
            app.AveragingWindow=changingValue;
            ComputeAverages(app);
            PlotData(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'COVID-19 Data Visualization (Data Source: Johns Hopkins University)';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, {'Daily Number of Cases and Deaths'; ''})
            zlabel(app.UIAxes, {''; ''})
            app.AveragedofdaysSliderLabel = uilabel(app.UIFigure);
            app.AveragedofdaysSliderLabel.Text = 'Averaged # of days';
            app.AveragedofdaysSlider = uislider(app.UIFigure);
            app.AveragedofdaysSlider.Limits = [1 15];
            app.AveragedofdaysSlider.ValueChangingFcn = createCallbackFcn(app, @AveragedofdaysSliderValueChanging, true);
            app.AveragedofdaysSlider.Value = 1;
            app.DatatoPlotButtonGroup = uibuttongroup(app.UIFigure);
            app.DatatoPlotButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DatatoPlotButtonGroupSelectionChanged, true);
            app.DatatoPlotButtonGroup.Title = 'Data to Plot';
            app.CasesButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.CasesButton.Text = 'Cases';
            app.CasesButton.Value = true;
            app.DeathsButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.DeathsButton.Text = 'Deaths';
            app.BothButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.BothButton.Text = 'Both';
            app.OptionButtonGroup = uibuttongroup(app.UIFigure);
            app.OptionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OptionButtonGroupSelectionChanged, true);
            app.OptionButtonGroup.Title = 'Option';
            app.CumulativeButton = uiradiobutton(app.OptionButtonGroup);
            app.CumulativeButton.Text = 'Cumulative';
            app.CumulativeButton.Value = true;
            app.DailyButton = uiradiobutton(app.OptionButtonGroup);
            app.DailyButton.Text = 'Daily';
            app.CountryListBoxLabel = uilabel(app.UIFigure);
            app.CountryListBoxLabel.Text = 'Country: ';
            app.CountryListBox = uilistbox(app.UIFigure);
            app.CountryListBox.Items = {'Item 1', 'Item 2', 'Item 3', 'Item 4', 'item 5', 'item 6', 'item 7'};
            app.CountryListBox.ValueChangedFcn = createCallbackFcn(app, @CountryListBoxValueChanged, true);
            app.StateorRegionListBoxLabel = uilabel(app.UIFigure);
            app.StateorRegionListBoxLabel.Text = 'State or Region: ';
            app.StateorRegionListBox = uilistbox(app.UIFigure);
            app.StateorRegionListBox.Items = {'Item 1', 'Item 2', 'Item 3', 'Item 4', 'item 5', 'item 6', 'item 7 ', ''};
            app.StateorRegionListBox.ValueChangedFcn = createCallbackFcn(app, @StateorRegionListBoxValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Covid_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end